classdef RoboticManipulator < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        MarekSykorkaModelofAngularRoboticManipulatorUIFigure  matlab.ui.Figure
        GridLayout                    matlab.ui.container.GridLayout
        LeftPanel                     matlab.ui.container.Panel
        LabelPhi1_max_title           matlab.ui.control.Label
        LabelPhi1_max_value           matlab.ui.control.Label
        LabelPhi2_max_title           matlab.ui.control.Label
        LabelPhi2_max_value           matlab.ui.control.Label
        LabelPhi3_max_title           matlab.ui.control.Label
        LabelPhi3_max_value           matlab.ui.control.Label
        LabelPhi1_min_title           matlab.ui.control.Label
        LabelPhi1_min_value           matlab.ui.control.Label
        LabelPhi2_min_title           matlab.ui.control.Label
        LabelPhi2_min_value           matlab.ui.control.Label
        LabelPhi3_min_title           matlab.ui.control.Label
        LabelPhi3_min_value           matlab.ui.control.Label
        LabelPhi1                     matlab.ui.control.Label
        LabelPhi2                     matlab.ui.control.Label
        LabelPhi3                     matlab.ui.control.Label
        EditFieldL1                   matlab.ui.control.NumericEditField
        EditFieldL2                   matlab.ui.control.NumericEditField
        EditFieldL3                   matlab.ui.control.NumericEditField
        Label_3                       matlab.ui.control.Label
        SliderPhi1                    matlab.ui.control.Slider
        SliderPhi2                    matlab.ui.control.Slider
        SliderPhi3                    matlab.ui.control.Slider
        Label_2                       matlab.ui.control.Label
        L1mmLabel                     matlab.ui.control.Label
        L2mmLabel                     matlab.ui.control.Label
        L3mmLabel                     matlab.ui.control.Label
        Label                         matlab.ui.control.Label
        RightPanel                    matlab.ui.container.Panel
        OrientationlockButton         matlab.ui.control.StateButton
        DraweverythingButton          matlab.ui.control.Button
        DrawABCworkingareaButton      matlab.ui.control.Button
        DrawXYworkingareaButton       matlab.ui.control.Button
        DrawRoboticManipulatorButton  matlab.ui.control.Button
        UIAxes                        matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = public)
        l1;
        l2;
        l3;
        
        phi1_min;
        phi1_max;
        phi2_min;
        phi2_max;
        phi3_min;
        phi3_max;    
        
        phi1;
        phi2;
        phi3;

        drawRM;
        drawXY;
        drawABC;
        orientationLock;

        krok = 5;
    end
    
    methods (Access = private)
        function myUpdateApp(app)
            appMain(app);
        end
    end    


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.l1 = 203;
            app.l2 = 178;
            app.l3 = 178;
            
            app.phi1_min = -90;
            app.phi1_max = 90;
            app.phi2_min = -55;
            app.phi2_max = 125;
            app.phi3_min = 0;
            app.phi3_max = 150;    
            
            app.phi1 = 0;
            app.phi2 = 45;
            app.phi3 = 90;

            app.drawRM = true;
            app.drawXY = true;
            app.drawABC = true;
            app.orientationLock = false;

            app.EditFieldL1.Value = app.l1;
            app.EditFieldL2.Value = app.l2;
            app.EditFieldL3.Value = app.l3;

            app.SliderPhi1.Value = app.phi1;
            app.LabelPhi1.Text = int2str(app.phi1);
            app.SliderPhi1.Limits = [app.phi1_min, app.phi1_max];
            app.SliderPhi1.MajorTicks = (app.phi1_min:10:app.phi1_max);

            app.SliderPhi2.Value = app.phi2;
            app.LabelPhi2.Text = int2str(app.phi2);
            app.SliderPhi2.Limits = [app.phi2_min, app.phi2_max];
            app.SliderPhi2.MajorTicks = (app.phi2_min:10:app.phi2_max);

            app.SliderPhi3.Value = app.phi3;
            app.LabelPhi3.Text = int2str(app.phi3);
            app.SliderPhi3.Limits = [app.phi3_min, app.phi3_max];
            app.SliderPhi3.MajorTicks = (app.phi3_min:10:app.phi3_max);

            cla(app.UIAxes);
            grid(app.UIAxes,'on');
            axis(app.UIAxes,'equal');
            hold(app.UIAxes,'on');
            view(app.UIAxes,[140 30]);
            xlabel(app.UIAxes,"'x' axis [mm]");
            ylabel(app.UIAxes,"'y' axis [mm]");
            zlabel(app.UIAxes,"'z' axis [mm]");

            app.myUpdateApp();
        end

        % Value changed function: EditFieldL1
        function EditFieldL1ValueChanged(app, event)
            app.l1 = app.EditFieldL1.Value;
            app.myUpdateApp();
        end

        % Value changed function: EditFieldL2
        function EditFieldL2ValueChanged(app, event)
            app.l2 = app.EditFieldL2.Value;
            app.myUpdateApp();
        end

        % Value changed function: EditFieldL3
        function EditFieldL3ValueChanged(app, event)
            app.l3 = app.EditFieldL3.Value;
            app.myUpdateApp();
        end

        % Value changed function: SliderPhi1
        function SliderPhi1ValueChanged(app, event)
            app.phi1 = app.SliderPhi1.Value;
            app.LabelPhi1.Text = int2str(app.phi1);
            app.myUpdateApp();
        end

        % Value changed function: SliderPhi2
        function SliderPhi2ValueChanged(app, event)
            app.phi2 = app.SliderPhi2.Value;
            app.LabelPhi2.Text = int2str(app.phi2);
            app.myUpdateApp();
        end

        % Value changed function: SliderPhi3
        function SliderPhi3ValueChanged(app, event)
            app.phi3 = app.SliderPhi3.Value;
            app.LabelPhi3.Text = int2str(app.phi3);
            app.myUpdateApp();
        end

        % Button pushed function: DraweverythingButton
        function DraweverythingButtonPushed(app, event)
            app.drawRM = true;
            app.drawXY = true;
            app.drawABC = true;
            app.myUpdateApp();
        end

        % Button pushed function: DrawABCworkingareaButton
        function DrawABCworkingareaButtonPushed(app, event)
            app.drawRM = false;
            app.drawXY = false;
            app.drawABC = true;
            app.myUpdateApp();
        end

        % Button pushed function: DrawXYworkingareaButton
        function DrawXYworkingareaButtonPushed(app, event)
            app.drawRM = false;
            app.drawXY = true;
            app.drawABC = false;
            app.myUpdateApp();
        end

        % Button pushed function: DrawRoboticManipulatorButton
        function DrawRoboticManipulatorButtonPushed(app, event)
            app.drawRM = true;
            app.drawXY = false;
            app.drawABC = false;
            app.myUpdateApp();
        end

        % Value changed function: OrientationlockButton
        function OrientationlockButtonValueChanged(app, event)
            app.orientationLock = app.OrientationlockButton.Value;
            app.myUpdateApp();
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.MarekSykorkaModelofAngularRoboticManipulatorUIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {479, 479};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {213, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.MarekSykorkaModelofAngularRoboticManipulatorUIFigure = uifigure('Visible', 'off');
            app.MarekSykorkaModelofAngularRoboticManipulatorUIFigure.Name = 'Marek Sykorka - Model of Angular Robotic Manipulator';
            app.MarekSykorkaModelofAngularRoboticManipulatorUIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.MarekSykorkaModelofAngularRoboticManipulatorUIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.Label = uilabel(app.LeftPanel);
            app.Label.Text = 'φ1 [deg]';
            app.L3mmLabel = uilabel(app.LeftPanel);
            app.L3mmLabel.Text = 'L3 [mm]';
            app.L2mmLabel = uilabel(app.LeftPanel);
            app.L2mmLabel.Text = 'L2 [mm]';
            app.L1mmLabel = uilabel(app.LeftPanel);
            app.L1mmLabel.Text = 'L1 [mm]';
            app.Label_2 = uilabel(app.LeftPanel);
            app.Label_2.Text = 'φ2 [deg]';
            app.SliderPhi3 = uislider(app.LeftPanel);
            app.SliderPhi3.MajorTicks = [];
            app.SliderPhi3.ValueChangedFcn = createCallbackFcn(app, @SliderPhi3ValueChanged, true);
            app.SliderPhi2 = uislider(app.LeftPanel);
            app.SliderPhi2.MajorTicks = [];
            app.SliderPhi2.ValueChangedFcn = createCallbackFcn(app, @SliderPhi2ValueChanged, true);
            app.SliderPhi1 = uislider(app.LeftPanel);
            app.SliderPhi1.MajorTicks = [];
            app.SliderPhi1.ValueChangedFcn = createCallbackFcn(app, @SliderPhi1ValueChanged, true);
            app.Label_3 = uilabel(app.LeftPanel);
            app.Label_3.Text = 'φ3 [deg]';
            app.EditFieldL3 = uieditfield(app.LeftPanel, 'numeric');
            app.EditFieldL3.Limits = [0 Inf];
            app.EditFieldL3.ValueChangedFcn = createCallbackFcn(app, @EditFieldL3ValueChanged, true);
            app.EditFieldL2 = uieditfield(app.LeftPanel, 'numeric');
            app.EditFieldL2.Limits = [0 Inf];
            app.EditFieldL2.ValueChangedFcn = createCallbackFcn(app, @EditFieldL2ValueChanged, true);
            app.EditFieldL1 = uieditfield(app.LeftPanel, 'numeric');
            app.EditFieldL1.Limits = [0 Inf];
            app.EditFieldL1.ValueChangedFcn = createCallbackFcn(app, @EditFieldL1ValueChanged, true);
            app.LabelPhi3 = uilabel(app.LeftPanel);
            app.LabelPhi2 = uilabel(app.LeftPanel);
            app.LabelPhi1 = uilabel(app.LeftPanel);
            app.LabelPhi3_min_value = uilabel(app.LeftPanel);
            app.LabelPhi3_min_value.Text = '0°';
            app.LabelPhi3_min_title = uilabel(app.LeftPanel);
            app.LabelPhi3_min_title.Text = 'φ3 min';
            app.LabelPhi2_min_value = uilabel(app.LeftPanel);
            app.LabelPhi2_min_value.Text = '-55°';
            app.LabelPhi2_min_title = uilabel(app.LeftPanel);
            app.LabelPhi2_min_title.Text = 'φ2 min';
            app.LabelPhi1_min_value = uilabel(app.LeftPanel);
            app.LabelPhi1_min_value.Text = '-90°';
            app.LabelPhi1_min_title = uilabel(app.LeftPanel);
            app.LabelPhi1_min_title.Text = 'φ1 min';
            app.LabelPhi3_max_value = uilabel(app.LeftPanel);
            app.LabelPhi3_max_value.Text = '150°';
            app.LabelPhi3_max_title = uilabel(app.LeftPanel);
            app.LabelPhi3_max_title.Text = 'φ3 max';
            app.LabelPhi2_max_value = uilabel(app.LeftPanel);
            app.LabelPhi2_max_value.Text = '125°';
            app.LabelPhi2_max_title = uilabel(app.LeftPanel);
            app.LabelPhi2_max_title.Text = 'φ2 max';
            app.LabelPhi1_max_value = uilabel(app.LeftPanel);
            app.LabelPhi1_max_value.Text = '90°';
            app.LabelPhi1_max_title = uilabel(app.LeftPanel);
            app.LabelPhi1_max_title.Text = 'φ1 max';
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.RightPanel);
            title(app.UIAxes, 'Robotic Manipulator')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.DrawRoboticManipulatorButton = uibutton(app.RightPanel, 'push');
            app.DrawRoboticManipulatorButton.ButtonPushedFcn = createCallbackFcn(app, @DrawRoboticManipulatorButtonPushed, true);
            app.DrawRoboticManipulatorButton.Text = 'Draw Robotic Manipulator';
            app.DrawXYworkingareaButton = uibutton(app.RightPanel, 'push');
            app.DrawXYworkingareaButton.ButtonPushedFcn = createCallbackFcn(app, @DrawXYworkingareaButtonPushed, true);
            app.DrawXYworkingareaButton.Text = 'Draw XY working area';
            app.DrawABCworkingareaButton = uibutton(app.RightPanel, 'push');
            app.DrawABCworkingareaButton.ButtonPushedFcn = createCallbackFcn(app, @DrawABCworkingareaButtonPushed, true);
            app.DrawABCworkingareaButton.Text = 'Draw ABC working area';
            app.DraweverythingButton = uibutton(app.RightPanel, 'push');
            app.DraweverythingButton.ButtonPushedFcn = createCallbackFcn(app, @DraweverythingButtonPushed, true);
            app.DraweverythingButton.Text = 'Draw everything';
            app.OrientationlockButton = uibutton(app.RightPanel, 'state');
            app.OrientationlockButton.ValueChangedFcn = createCallbackFcn(app, @OrientationlockButtonValueChanged, true);
            app.OrientationlockButton.Text = 'Orientation lock';
            % Show the figure after all components are created
            app.MarekSykorkaModelofAngularRoboticManipulatorUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = RoboticManipulator

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.MarekSykorkaModelofAngularRoboticManipulatorUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.MarekSykorkaModelofAngularRoboticManipulatorUIFigure)
        end
    end
end